classdef Read_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        YesButton  matlab.ui.control.Button
        NoButton   matlab.ui.control.Button
        GraphP1    matlab.ui.control.UIAxes
        GraphO1    matlab.ui.control.UIAxes
        GraphPz    matlab.ui.control.UIAxes
        GraphOz    matlab.ui.control.UIAxes
        GraphPOz   matlab.ui.control.UIAxes
        GraphO2    matlab.ui.control.UIAxes
        Label      matlab.ui.control.Label
        Label_3    matlab.ui.control.Label
        Label2     matlab.ui.control.Label
        Label3     matlab.ui.control.Label
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        loop
        cfg
        procDatCut
        datRes % Description
        rawDat % Description
    end


    methods (Access = private)
        
        function results = showData(app, procDatCut, cfg, loop)
                x = [(-cfg.pre.prestim*1000) : ((cfg.pre.prestim + cfg.pre.poststim)/size(procDatCut,2)*1000) : (cfg.pre.poststim*1000)];
                x = x(1:end-1);
                
                plot(app.GraphP1, x, procDatCut(67,:, loop));
                yline(app.GraphP1, 0);
                xline(app.GraphP1, 0);
                xlim(app.GraphP1, [(-cfg.pre.prestim*1000) (cfg.pre.poststim*1000)]);
                set(app.GraphP1,'YDir','reverse');
                plot(app.GraphPz, x, procDatCut(62,:, loop));
                yline(app.GraphPz, 0);
                xline(app.GraphPz, 0);
                xlim(app.GraphPz, [(-cfg.pre.prestim*1000) (cfg.pre.poststim*1000)]);
                set(app.GraphPz,'YDir','reverse');
                plot(app.GraphPOz, x, procDatCut(72,:, loop));
                yline(app.GraphPOz, 0);
                xline(app.GraphPOz, 0);
                xlim(app.GraphPOz, [(-cfg.pre.prestim*1000) (cfg.pre.poststim*1000)]);
                set(app.GraphPOz,'YDir','reverse');
                plot(app.GraphO1, x, procDatCut(70,:, loop));
                yline(app.GraphO1, 0);
                xline(app.GraphO1, 0);
                xlim(app.GraphO1, [(-cfg.pre.prestim*1000) (cfg.pre.poststim*1000)]);
                set(app.GraphO1,'YDir','reverse');
                plot(app.GraphOz, x, procDatCut(75,:, loop));
                yline(app.GraphOz, 0);
                xline(app.GraphOz, 0);
                xlim(app.GraphOz, [(-cfg.pre.prestim*1000) (cfg.pre.poststim*1000)]);
                set(app.GraphOz,'YDir','reverse');
                plot(app.GraphO2, x, procDatCut(83,:, loop));
                yline(app.GraphO2, 0);
                xline(app.GraphO2, 0);
                xlim(app.GraphO2, [(-cfg.pre.prestim*1000) (cfg.pre.poststim*1000)]);
                set(app.GraphO2,'YDir','reverse');
                app.Label3.Text = {cfg.name};
                app.Label_3.Text = {[num2str(loop) ' out of ' num2str(size(procDatCut,3))]};
                timepoint = round(cfg.trl(cfg.trlIdx(loop),1) / 500);
                app.Label2.Text = {['The trial begins at ' num2str(timepoint) ' sec']};
        end
        
        function results = saveDat(app)
            nametmp  = split(app.cfg.dataset, '.');
            name = append(nametmp(1),'_Speed_',int2str(app.cfg.speed),'.mat');
            clear nametmp
            cfg = app.cfg;
            procDatCut = app.procDatCut;
            rawDat = app.rawDat;
            datRes = app.datRes;
            save(name{1,1}, 'cfg', 'procDatCut', 'rawDat', 'datRes','-append')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, cfg, procDatCut, rawDat)
            showData(app, procDatCut, cfg, 1)
            app.loop = 1;
            app.cfg = cfg;
            app.procDatCut = procDatCut;
            app.rawDat = rawDat;
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
        app.datRes(app.loop) = app.cfg.speed;
        if app.loop < size(app.procDatCut,3) 
        app.loop = app.loop + 1;
            showData(app, app.procDatCut, app.cfg, app.loop)
        else
            saveDat(app)
            delete(app)
        end
        end

        % Button pushed function: NoButton
        function NoButtonPushed(app, event)
        app.datRes(app.loop) = 0;
        if app.loop < size(app.procDatCut,3) 
        app.loop = app.loop + 1;
            showData(app, app.procDatCut, app.cfg, app.loop)           
        else
            saveDat(app)
            delete(app)
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.YesButton = uibutton(app.UIFigure, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes';
            app.NoButton = uibutton(app.UIFigure, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton.Text = 'No';
            app.GraphP1 = uiaxes(app.UIFigure);
            title(app.GraphP1, 'P1 (67)')
            xlabel(app.GraphP1, 'Time in ms')
            ylabel(app.GraphP1, 'Amplitude in uV')
            app.GraphO1 = uiaxes(app.UIFigure);
            title(app.GraphO1, 'O1 (70)')
            xlabel(app.GraphO1, 'Time in ms')
            ylabel(app.GraphO1, 'Amplitude in uV')
            app.GraphPz = uiaxes(app.UIFigure);
            title(app.GraphPz, 'Pz (62)')
            xlabel(app.GraphPz, 'Time in ms')
            ylabel(app.GraphPz, 'Amplitude in uV')
            app.GraphOz = uiaxes(app.UIFigure);
            title(app.GraphOz, 'Oz (75)')
            xlabel(app.GraphOz, 'Time in ms')
            ylabel(app.GraphOz, 'Amplitude in uV')
            app.GraphPOz = uiaxes(app.UIFigure);
            title(app.GraphPOz, 'POz (72)')
            xlabel(app.GraphPOz, 'Time in ms')
            ylabel(app.GraphPOz, 'Amplitude in uV')
            app.GraphO2 = uiaxes(app.UIFigure);
            title(app.GraphO2, 'O2 (83)')
            xlabel(app.GraphO2, 'Time in ms')
            ylabel(app.GraphO2, 'Amplitude in uV')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Does a looming realted '; 'response occure?'};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Label3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Read_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end